classdef ShowLearningIMGapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
        Slider    matlab.ui.control.Slider
    end

    
    properties (Access = private)
        imgs % Description
    end
    
    methods (Access = public)
        
        function addImage(app,newImage)
            if isempty(app.imgs)
                app.imgs = {newImage};
            else
                app.imgs{end+1} = newImage;
            end
            nImgs = numel(app.imgs);
            % Обновляем настройки слайдера
            if nImgs == 1
                % если только одно изображение
                app.Slider.Limits = [1 2];   % фиктивный диапазон
                app.Slider.Value = 1;
                app.Slider.MajorTicks = 1;   % одна метка
            else
                app.Slider.Limits = [1 nImgs];
                app.Slider.Value = nImgs;
                app.Slider.MajorTicks = 1:nImgs;
            end
            
            % Показываем новое изображение
            app.Image.ImageSource = newImage;
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            changedValue = event.Value;
            if isempty(app.imgs)
                return;
            end
            app.Image.ImageSource = app.imgs{changedValue};
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            if isempty(app.imgs)
                return;
            end
            app.Image.ImageSource = app.imgs{changingValue};
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 2];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100];
            app.Slider.Step = 1;
            app.Slider.Value = 1;
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShowLearningIMGapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end